classdef ToolBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        alsBtn      matlab.ui.control.Button
        play        matlab.ui.control.Button
        filePicker  matlab.ui.control.Button
        RoomXLabel  matlab.ui.control.Label
        roomX       matlab.ui.control.Knob
        RoomYLabel  matlab.ui.control.Label
        RoomZLabel  matlab.ui.control.Label
        TabGroup    matlab.ui.container.TabGroup
        Tab         matlab.ui.container.Tab
        Tab2        matlab.ui.container.Tab
        roomZ       matlab.ui.control.Knob
        roomY       matlab.ui.control.Knob
    end

    
    properties (Access = private)
        x % Description
        fs % Description
        path = ""
        file
        alsRoom = [7,5,3];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup_script();
        end

        % Button pushed function: alsBtn
        function alsBtnPushed(app, event)
            if app.path ~= ""
            app.alsBtn.Enable = 'off';
            [app.x,app.fs] = als(app.x,app.fs,app.alsRoom);
            app.alsBtn.Enable = 'on';
            else
                alert("Choose file");
            end
       
        end

        % Button pushed function: play
        function playPushed(app, event)
            soundsc(app.x, app.fs);
        end

        % Button pushed function: filePicker
        function filePickerButtonPushed(app, event)
            [app.file,app.path] = uigetfile('*.wav');
            disp(app.file)
            [app.x,app.fs] = audioread(strcat(app.path,app.file));
        end

        % Value changed function: roomX
        function roomXValueChanged(app, event)
            value = app.roomX.Value;
            app.alsRoom(1)=value;
        end

        % Value changed function: roomY
        function roomYValueChanged(app, event)
            value = app.roomY.Value;
            app.alsRoom(2)=value;
        end

        % Value changed function: roomZ
        function roomZValueChanged(app, event)
            value = app.roomZ.Value;
            app.alsRoom(3)=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.alsBtn = uibutton(app.UIFigure, 'push');
            app.alsBtn.ButtonPushedFcn = createCallbackFcn(app, @alsBtnPushed, true);
            app.alsBtn.Text = 'als';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playPushed, true);
            app.play.Text = 'play';
            app.filePicker = uibutton(app.UIFigure, 'push');
            app.filePicker.ButtonPushedFcn = createCallbackFcn(app, @filePickerButtonPushed, true);
            app.filePicker.Text = 'select File';
            app.RoomXLabel = uilabel(app.UIFigure);
            app.RoomXLabel.Text = 'RoomX';
            app.roomX = uiknob(app.UIFigure, 'continuous');
            app.roomX.Limits = [0 10];
            app.roomX.ValueChangedFcn = createCallbackFcn(app, @roomXValueChanged, true);
            app.RoomYLabel = uilabel(app.UIFigure);
            app.RoomYLabel.Text = 'RoomY';
            app.roomY = uiknob(app.UIFigure, 'continuous');
            app.roomY.Limits = [0 10];
            app.roomY.ValueChangedFcn = createCallbackFcn(app, @roomYValueChanged, true);
            app.RoomZLabel = uilabel(app.UIFigure);
            app.RoomZLabel.Text = 'RoomZ';
            app.roomZ = uiknob(app.UIFigure, 'continuous');
            app.roomZ.Limits = [0 10];
            app.roomZ.ValueChangedFcn = createCallbackFcn(app, @roomZValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ToolBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end